classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SolutionofExactandNonExactDEUIFigure  matlab.ui.Figure
        HTML                          matlab.ui.control.HTML
        EnterNxyLabel                 matlab.ui.control.Label
        EnterMxyLabel                 matlab.ui.control.Label
        ReduceequationtoMdxNdy0Label  matlab.ui.control.Label
        IFTextArea                    matlab.ui.control.TextArea
        IFTextAreaLabel               matlab.ui.control.Label
        extraTextArea                 matlab.ui.control.TextArea
        extraTextAreaLabel            matlab.ui.control.Label
        NTextArea                     matlab.ui.control.TextArea
        NTextAreaLabel                matlab.ui.control.Label
        ansTextArea                   matlab.ui.control.TextArea
        ansTextAreaLabel              matlab.ui.control.Label
        MTextArea                     matlab.ui.control.TextArea
        MTextAreaLabel                matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            syms x y c
            
            M = str2sym(app.MTextArea.Value);
            N = str2sym(app.NTextArea.Value);
            dM=diff(M,y);
            dN=diff(N,x);

            if dM==dN
                app.extraTextArea.Value = "Equation is Exact";
            else 
                app.extraTextArea.Value = "Equation is Non-Exact";
                IF=str2sym(app.IFTextArea.Value);
                M=M*IF;
                N=N*IF;
            end


            M_ans=int(M,x);
            
            A=diff(M_ans, y);
            N_without_x=N-A;
            
            N_ans=int(N_without_x, y);
            
            General_solution=M_ans+N_ans==c;
            
            
            %app.ansTextArea.Value = char(General_solution);
            app.ansTextArea.Value = latex(General_solution);

            % 1. Convert symbolic to LaTeX string
            raw_latex = latex(General_solution);
            
            % 2. Build the "Textbook" HTML string
            % This template includes the MathJax engine and centering
            html_template = [...
                '<html>' ...
                '<head>' ...
                '<script src="https://polyfill.io/v3/polyfill.min.js?features=es6"></script>' ...
                '<script id="MathJax-script" async src="https://cdn.jsdelivr.net/npm/mathjax@3/es5/tex-mml-chtml.js"></script>' ...
                '</head>' ...
                '<body style="background-color:transparent; display:flex; justify-content:center; align-items:center; height:100vh; margin:0;">' ...
                '<div style="font-size:24px;">' ...
                '\[ ' char(raw_latex) ' \]' ... % '\[ \]' triggers high-quality display math
                '</div>' ...
                '</body>' ...
                '</html>'];
            
            % 3. Assign to your HTML component
            app.HTML.HTMLSource = html_template;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SolutionofExactandNonExactDEUIFigure = uifigure('Visible', 'off');
            app.SolutionofExactandNonExactDEUIFigure.Name = 'Solution of Exact and Non-Exact DE';
            app.CalculateButton = uibutton(app.SolutionofExactandNonExactDEUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MTextAreaLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.MTextAreaLabel.Text = 'M';
            app.MTextArea = uitextarea(app.SolutionofExactandNonExactDEUIFigure);
            app.ansTextAreaLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.ansTextAreaLabel.Text = 'ans';
            app.ansTextArea = uitextarea(app.SolutionofExactandNonExactDEUIFigure);
            app.NTextAreaLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.NTextAreaLabel.Text = 'N';
            app.NTextArea = uitextarea(app.SolutionofExactandNonExactDEUIFigure);
            app.extraTextAreaLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.extraTextAreaLabel.Text = 'extra';
            app.extraTextArea = uitextarea(app.SolutionofExactandNonExactDEUIFigure);
            app.IFTextAreaLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.IFTextAreaLabel.Text = 'IF';
            app.IFTextArea = uitextarea(app.SolutionofExactandNonExactDEUIFigure);
            app.ReduceequationtoMdxNdy0Label = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.ReduceequationtoMdxNdy0Label.Text = 'Reduce equation to Mdx+Ndy=0';
            app.EnterMxyLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.EnterMxyLabel.Text = 'Enter M(x,y)';
            app.EnterNxyLabel = uilabel(app.SolutionofExactandNonExactDEUIFigure);
            app.EnterNxyLabel.Text = 'Enter N(x,y)';
            app.HTML = uihtml(app.SolutionofExactandNonExactDEUIFigure);
            % Show the figure after all components are created
            app.SolutionofExactandNonExactDEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SolutionofExactandNonExactDEUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SolutionofExactandNonExactDEUIFigure)
        end
    end
end